classdef GeneticAlgorithm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneticAlgorithmUIFigure       matlab.ui.Figure
        OutputFilenameEditFieldLabel   matlab.ui.control.Label
        OutputFilenameEditField        matlab.ui.control.EditField
        OutputFileLocationLabel        matlab.ui.control.Label
        OutputFileLocationEditField    matlab.ui.control.EditField
        GeneticAlgorithmLabel          matlab.ui.control.Label
        GenerateButton                 matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        NumberofChromosomesLabel       matlab.ui.control.Label
        NumberofChromosomesEditField   matlab.ui.control.NumericEditField
        StringsPerChromosomeLabel      matlab.ui.control.Label
        StringsPerChromosomeEditField  matlab.ui.control.NumericEditField
        BitsPerStringEditFieldLabel    matlab.ui.control.Label
        BitsPerStringEditField         matlab.ui.control.NumericEditField
        FunctionEditFieldLabel         matlab.ui.control.Label
        FunctionEditField              matlab.ui.control.EditField
        TypeofValuesDropDownLabel      matlab.ui.control.Label
        TypeofValuesDropDown           matlab.ui.control.DropDown
        MinMaxLabel                    matlab.ui.control.Label
        MinMaxDropDown                 matlab.ui.control.DropDown
        EvaluationLabel                matlab.ui.control.Label
        PopulationGenerationLabel      matlab.ui.control.Label
        OutputFileLabel                matlab.ui.control.Label
        UseRandomInitialPopulationButton  matlab.ui.control.StateButton
        SetinitialPopulationButton     matlab.ui.control.Button
        DecimalPlacesEditFieldLabel    matlab.ui.control.Label
        DecimalPlacesEditField         matlab.ui.control.NumericEditField
        CrossOverLabel                 matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PointButton                    matlab.ui.control.RadioButton
        PointsButton                   matlab.ui.control.RadioButton
        SetRangesDefault010Button      matlab.ui.control.Button
        GenerationLabel                matlab.ui.control.Label
        StopatGenerationLabel          matlab.ui.control.Label
        StopatGenerationEditField      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GeneticAlgorithmUIFigure.Visible = 'off';
            bits = app.BitsPerStringEditField.Value;
            app.DecimalPlacesEditField.Limits = [0 bits];
            randInitPop = app.UseRandomInitialPopulationButton.Value;
            app.TypeofValuesDropDown.ItemsData =  [1 2];
            if randInitPop
                app.SetinitialPopulationButton.Enable = 0;
            else
                app.SetinitialPopulationButton.Enable = 1;
            end
            if (app.TypeofValuesDropDown.Value == 2)
                app.DecimalPlacesEditField.Enable = 1;
            else
                app.DecimalPlacesEditField.Enable = 0;
            end
            global Err
            Err = [0 0 0 0 0 0 1];     %variable for tracking errors
            Err(7) = isempty(app.OutputFileLocationEditField.Value);
            if any(Err) %Check for errors
                %Disable Generate Button
                app.GenerateButton.Enable = 0;
            else
                %Enable Generate Button
                app.GenerateButton.Enable = 1;
            end
            movegui(app.GeneticAlgorithmUIFigure,'center')
            app.GeneticAlgorithmUIFigure.Visible = 'on';
        end

        % Value changed function: NumberofChromosomesEditField
        function NumberofChromosomesEditFieldValueChanged(app, event)
            value = app.NumberofChromosomesEditField.Value;
            if mod(value,2) ~= 0
                app.NumberofChromosomesEditField.BackgroundColor = '#EDB120';
            else
                app.NumberofChromosomesEditField.BackgroundColor = '#FFFFFF';
            end
        end

        % Value changed function: BitsPerStringEditField
        function BitsPerStringCheck(app, event)
            value = app.BitsPerStringEditField.Value;
            app.DecimalPlacesEditField.Limits = [0 value];
        end

        % Value changed function: UseRandomInitialPopulationButton
        function UseRandomInitialPopulationButtonCheck(app, event)
            value = app.UseRandomInitialPopulationButton.Value;
            if value
                app.SetinitialPopulationButton.Enable = 0;
            else
                app.SetinitialPopulationButton.Enable = 1;
            end
        end

        % Value changing function: OutputFilenameEditField
        function OutputFilenameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            global Err
            if (~isempty(changingValue))
                app.OutputFilenameEditField.BackgroundColor = '#FFFFFF';
                Err(6) = 0;
            else
                app.OutputFilenameEditField.BackgroundColor = '#EDB120';
                Err(6) = 1;
            end
            verifyVals(app, event);
        end

        % Value changing function: OutputFileLocationEditField
        function OutputFileLocationEditFieldValueChanging(app, event)
            changingValue = event.Value;
            global Err
            if (~isempty(changingValue))
                app.OutputFileLocationEditField.BackgroundColor = '#FFFFFF';
                Err(7) = 0;
            else
                app.OutputFileLocationEditField.BackgroundColor = '#EDB120';
                Err(7) = 1;
            end
            verifyVals(app, event);
        end

        % Value changed function: OutputFileLocationEditField
        function OutputFileLocationEditFieldValueChanged(app, event)
            value = app.OutputFileLocationEditField.Value;
            global Err
            if (~isempty(value))
                app.OutputFileLocationEditField.BackgroundColor = '#FFFFFF';
                Err(7) = 0;
            else
                app.OutputFileLocationEditField.BackgroundColor = '#EDB120';
                Err(7) = 1;
            end
            verifyVals(app, event);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global Err
            dir = uigetdir;
            if dir
                app.OutputFileLocationEditField.Value = dir;
            end
            if (~isempty(app.OutputFileLocationEditField.Value))
                app.OutputFileLocationEditField.BackgroundColor = '#FFFFFF';
                Err(7) = 0;
            else
                app.OutputFileLocationEditField.BackgroundColor = '#EDB120';
                Err(7) = 1;
            end
            verifyVals(app, event);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
                        
        end

        % Callback function: GeneticAlgorithmUIFigure, 
        % GeneticAlgorithmUIFigure, GeneticAlgorithmUIFigure
        function verifyVals(app, event)
            global Err
            if any(Err) %Check for errors
                %Disable Generate Button
                app.GenerateButton.Enable = 0;
            else
                %Enable Generate Button
                app.GenerateButton.Enable = 1;
            end
        end

        % Value changed function: TypeofValuesDropDown
        function TypeofValuesDropDownValueChanged(app, event)
            value = app.TypeofValuesDropDown.Value;
            if (value == 2)
                app.DecimalPlacesEditField.Enable = 1;
            else
                app.DecimalPlacesEditField.Enable = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneticAlgorithmUIFigure = uifigure('Visible', 'off');
            app.GeneticAlgorithmUIFigure.Name = 'Genetic Algorithm';
            app.GeneticAlgorithmUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @verifyVals, true);
            app.GeneticAlgorithmUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @verifyVals, true);
            app.GeneticAlgorithmUIFigure.KeyReleaseFcn = createCallbackFcn(app, @verifyVals, true);
            app.OutputFilenameEditFieldLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.OutputFilenameEditFieldLabel.Text = 'Output Filename:';
            app.OutputFilenameEditField = uieditfield(app.GeneticAlgorithmUIFigure, 'text');
            app.OutputFilenameEditField.ValueChangingFcn = createCallbackFcn(app, @OutputFilenameEditFieldValueChanging, true);
            app.OutputFilenameEditField.Value = 'GeneticAlgorithm';
            app.OutputFileLocationLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.OutputFileLocationLabel.Text = 'Output File Location:';
            app.OutputFileLocationEditField = uieditfield(app.GeneticAlgorithmUIFigure, 'text');
            app.OutputFileLocationEditField.ValueChangedFcn = createCallbackFcn(app, @OutputFileLocationEditFieldValueChanged, true);
            app.OutputFileLocationEditField.ValueChangingFcn = createCallbackFcn(app, @OutputFileLocationEditFieldValueChanging, true);
            app.OutputFileLocationEditField.Tooltip = {''};
            app.GeneticAlgorithmLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.GeneticAlgorithmLabel.Text = 'Genetic Algorithm';
            app.GenerateButton = uibutton(app.GeneticAlgorithmUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.BrowseButton = uibutton(app.GeneticAlgorithmUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.NumberofChromosomesLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.NumberofChromosomesLabel.Text = 'Number of Chromosomes:';
            app.NumberofChromosomesEditField = uieditfield(app.GeneticAlgorithmUIFigure, 'numeric');
            app.NumberofChromosomesEditField.LowerLimitInclusive = 'off';
            app.NumberofChromosomesEditField.UpperLimitInclusive = 'off';
            app.NumberofChromosomesEditField.Limits = [0 Inf];
            app.NumberofChromosomesEditField.RoundFractionalValues = 'on';
            app.NumberofChromosomesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofChromosomesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofChromosomesEditFieldValueChanged, true);
            app.NumberofChromosomesEditField.Tooltip = {'Value must be an even number'};
            app.NumberofChromosomesEditField.Value = 10;
            app.StringsPerChromosomeLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.StringsPerChromosomeLabel.Text = 'Strings Per Chromosome:';
            app.StringsPerChromosomeEditField = uieditfield(app.GeneticAlgorithmUIFigure, 'numeric');
            app.StringsPerChromosomeEditField.LowerLimitInclusive = 'off';
            app.StringsPerChromosomeEditField.UpperLimitInclusive = 'off';
            app.StringsPerChromosomeEditField.Limits = [0 Inf];
            app.StringsPerChromosomeEditField.RoundFractionalValues = 'on';
            app.StringsPerChromosomeEditField.ValueDisplayFormat = '%.0f';
            app.StringsPerChromosomeEditField.Value = 5;
            app.BitsPerStringEditFieldLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.BitsPerStringEditFieldLabel.Text = 'Bits Per String:';
            app.BitsPerStringEditField = uieditfield(app.GeneticAlgorithmUIFigure, 'numeric');
            app.BitsPerStringEditField.LowerLimitInclusive = 'off';
            app.BitsPerStringEditField.UpperLimitInclusive = 'off';
            app.BitsPerStringEditField.Limits = [0 Inf];
            app.BitsPerStringEditField.RoundFractionalValues = 'on';
            app.BitsPerStringEditField.ValueDisplayFormat = '%.0f';
            app.BitsPerStringEditField.ValueChangedFcn = createCallbackFcn(app, @BitsPerStringCheck, true);
            app.BitsPerStringEditField.Value = 7;
            app.FunctionEditFieldLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.FunctionEditFieldLabel.Text = 'Function:';
            app.FunctionEditField = uieditfield(app.GeneticAlgorithmUIFigure, 'text');
            app.TypeofValuesDropDownLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.TypeofValuesDropDownLabel.Text = 'Type of Values';
            app.TypeofValuesDropDown = uidropdown(app.GeneticAlgorithmUIFigure);
            app.TypeofValuesDropDown.Items = {'Binary', 'Real'};
            app.TypeofValuesDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofValuesDropDownValueChanged, true);
            app.TypeofValuesDropDown.Value = 'Binary';
            app.MinMaxLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.MinMaxLabel.Text = 'Min/Max:';
            app.MinMaxDropDown = uidropdown(app.GeneticAlgorithmUIFigure);
            app.MinMaxDropDown.Items = {'Min', 'Max'};
            app.MinMaxDropDown.Value = 'Min';
            app.EvaluationLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.EvaluationLabel.Text = 'Evaluation';
            app.PopulationGenerationLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.PopulationGenerationLabel.Text = 'Population Generation';
            app.OutputFileLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.OutputFileLabel.Text = 'Output File';
            app.UseRandomInitialPopulationButton = uibutton(app.GeneticAlgorithmUIFigure, 'state');
            app.UseRandomInitialPopulationButton.ValueChangedFcn = createCallbackFcn(app, @UseRandomInitialPopulationButtonCheck, true);
            app.UseRandomInitialPopulationButton.Text = 'Use Random Initial Population';
            app.UseRandomInitialPopulationButton.Value = true;
            app.SetinitialPopulationButton = uibutton(app.GeneticAlgorithmUIFigure, 'push');
            app.SetinitialPopulationButton.Text = 'Set initial Population';
            app.DecimalPlacesEditFieldLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.DecimalPlacesEditFieldLabel.Text = 'Decimal Places:';
            app.DecimalPlacesEditField = uieditfield(app.GeneticAlgorithmUIFigure, 'numeric');
            app.DecimalPlacesEditField.Limits = [0 Inf];
            app.DecimalPlacesEditField.RoundFractionalValues = 'on';
            app.DecimalPlacesEditField.ValueDisplayFormat = '%.0f';
            app.DecimalPlacesEditField.Tooltip = {'Value must not be more than bits per string'};
            app.CrossOverLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.CrossOverLabel.Text = 'Cross-Over';
            app.ButtonGroup = uibuttongroup(app.GeneticAlgorithmUIFigure);
            app.PointButton = uiradiobutton(app.ButtonGroup);
            app.PointButton.Text = '1 Point';
            app.PointButton.Value = true;
            app.PointsButton = uiradiobutton(app.ButtonGroup);
            app.PointsButton.Text = '2 Points';
            app.SetRangesDefault010Button = uibutton(app.GeneticAlgorithmUIFigure, 'push');
            app.SetRangesDefault010Button.Text = 'Set Ranges (Default 0-10)';
            app.GenerationLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.GenerationLabel.Text = 'Generation';
            app.StopatGenerationLabel = uilabel(app.GeneticAlgorithmUIFigure);
            app.StopatGenerationLabel.Text = 'Stop at Generation:';
            app.StopatGenerationEditField = uieditfield(app.GeneticAlgorithmUIFigure, 'numeric');
            app.StopatGenerationEditField.UpperLimitInclusive = 'off';
            app.StopatGenerationEditField.Limits = [0 Inf];
            app.StopatGenerationEditField.RoundFractionalValues = 'on';
            app.StopatGenerationEditField.ValueDisplayFormat = '%.0f';
            app.StopatGenerationEditField.Value = 10;
            % Show the figure after all components are created
            app.GeneticAlgorithmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneticAlgorithm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneticAlgorithmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneticAlgorithmUIFigure)
        end
    end
end